classdef LiveTrx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFig_LiveTrx                  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AdddataTab                     matlab.ui.container.Tab
        LoadrawdataButton              matlab.ui.control.Button
        PathEditFieldLabel             matlab.ui.control.Label
        PathEditField                  matlab.ui.control.EditField
        RuninitialProjectionButton     matlab.ui.control.Button
        AddmetadataButton              matlab.ui.control.Button
        AddMetadataLamp                matlab.ui.control.Lamp
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        NicknameDropDownLabel          matlab.ui.control.Label
        NicknameDropDown               matlab.ui.control.DropDown
        RepeatEditFieldLabel           matlab.ui.control.Label
        RepeatEditField                matlab.ui.control.NumericEditField
        Channel1DropDownLabel          matlab.ui.control.Label
        Channel1DropDown               matlab.ui.control.DropDown
        Channel2DropDownLabel          matlab.ui.control.Label
        Channel2DropDown               matlab.ui.control.DropDown
        InitialProjectionLamp          matlab.ui.control.Lamp
        T0EditFieldLabel               matlab.ui.control.Label
        T0EditField                    matlab.ui.control.NumericEditField
        FlipEditFieldLabel             matlab.ui.control.Label
        FlipEditField                  matlab.ui.control.EditField
        DelayEditFieldLabel            matlab.ui.control.Label
        DelayEditField                 matlab.ui.control.NumericEditField
        ToEditFieldLabel               matlab.ui.control.Label
        ToEditField                    matlab.ui.control.NumericEditField
        SplitEarlyEditFieldLabel       matlab.ui.control.Label
        SplitEarlyEditField            matlab.ui.control.NumericEditField
        FromEditFieldLabel             matlab.ui.control.Label
        FromEditField                  matlab.ui.control.NumericEditField
        Channel3DropDownLabel          matlab.ui.control.Label
        Channel3DropDown               matlab.ui.control.DropDown
        UIAxesMaxProj                  matlab.ui.control.UIAxes
        UIAxesXT                       matlab.ui.control.UIAxes
        UIAxesZT                       matlab.ui.control.UIAxes
        UIAxesYT                       matlab.ui.control.UIAxes
        RuntrackingTab                 matlab.ui.container.Tab
        SelectdataPanel                matlab.ui.container.Panel
        SearchRegExpButton             matlab.ui.control.Button
        Metadata                       matlab.ui.control.Table
        SelectallMetadataButton        matlab.ui.control.Button
        DeselectallMetadataButton      matlab.ui.control.Button
        SearchRegExpEditField          matlab.ui.control.EditField
        DeleteselectedMetadataButton   matlab.ui.control.Button
        SaveMetadataButton             matlab.ui.control.Button
        RuntrackingPanel               matlab.ui.container.Panel
        RunselectedButton_3            matlab.ui.control.Button
        ProjectionsButton              matlab.ui.control.StateButton
        ProjectionsOrthogonalButton    matlab.ui.control.StateButton
        SegmtrackingButton             matlab.ui.control.StateButton
        Properties3DButton             matlab.ui.control.StateButton
        TrackingLamp                   matlab.ui.control.Lamp
        SettingshistonesLabel          matlab.ui.control.Label
        DefaultsettingsHis             matlab.ui.control.TextArea
        RegionButtonGroup              matlab.ui.container.ButtonGroup
        RegionnoneButton               matlab.ui.control.ToggleButton
        RegiondrawButton               matlab.ui.control.ToggleButton
        RegionoverwriteButton          matlab.ui.control.ToggleButton
        MetadataDropDownLabel_4        matlab.ui.control.Label
        AnalizetranscriptionButton     matlab.ui.control.StateButton
        DefaultsettingsNucMemb         matlab.ui.control.TextArea
        DefaultsettingsCellMemb        matlab.ui.control.TextArea
        ProgressTracking               matlab.ui.control.EditField
        MembLengthButton               matlab.ui.control.StateButton
        SettingsnuclearmembranesLabel  matlab.ui.control.Label
        SettingscellmembranesLabel     matlab.ui.control.Label
        RegionButtonAT                 matlab.ui.container.ButtonGroup
        ButtonATMCP                    matlab.ui.control.ToggleButton
        ButtonATPCP                    matlab.ui.control.ToggleButton
        ButtonATmean                   matlab.ui.control.ToggleButton
        DefaultsettingsAT              matlab.ui.control.TextArea
        SettingsanalizetranscriptionLabel  matlab.ui.control.Label
        AnalyzeTab                     matlab.ui.container.Tab
        SelectexperimentstocomparePanel  matlab.ui.container.Panel
        loadProfileButton              matlab.ui.control.Button
        ProfileDropDownLabel           matlab.ui.control.Label
        ProfileDropDown                matlab.ui.control.DropDown
        NewProfileNameButton           matlab.ui.control.Button
        ProfilesTable                  matlab.ui.control.Table
        NewProfileTable                matlab.ui.control.Table
        SelectallProfileButton         matlab.ui.control.Button
        DeleteselectedProfileButton    matlab.ui.control.Button
        AddnewProfileButton            matlab.ui.control.Button
        DeselectallProfileButton       matlab.ui.control.Button
        ProfileLamp                    matlab.ui.control.Lamp
        SaveProfileButton              matlab.ui.control.Button
        NewProfileNameField            matlab.ui.control.EditField
        DuplicateProfileButton         matlab.ui.control.Button
        Palettes                       matlab.ui.control.UIAxes
        RuncomparisonsPanel            matlab.ui.container.Panel
        PropertiestoRun                matlab.ui.control.EditField
        RunselectedButton_2            matlab.ui.control.Button
        OutputfolderEditFieldLabel     matlab.ui.control.Label
        OutputfolderEditField          matlab.ui.control.EditField
        MeanHeatmapsButton             matlab.ui.control.StateButton
        BurstingButton                 matlab.ui.control.StateButton
        OnoffButton                    matlab.ui.control.StateButton
        OfftimesButton                 matlab.ui.control.StateButton
        MembranesButton                matlab.ui.control.StateButton
        GastrulationButton             matlab.ui.control.StateButton
        DPropertiesButton              matlab.ui.control.StateButton
        RunCompLamp                    matlab.ui.control.Lamp
        PlotselectedCheckBox           matlab.ui.control.CheckBox
        DefaultsettingsTextAreaLabel   matlab.ui.control.Label
        DefaultsettingsBursting        matlab.ui.control.TextArea
        growthCheckBox                 matlab.ui.control.CheckBox
        boxplotslengthCheckBox         matlab.ui.control.CheckBox
        corronsetvslengthCheckBox      matlab.ui.control.CheckBox
        YLimEditField_2Label           matlab.ui.control.Label
        YLimGast                       matlab.ui.control.EditField
        ProgressComp                   matlab.ui.control.EditField
        GastTimeCheckBox               matlab.ui.control.CheckBox
        ConfirmCheckBox                matlab.ui.control.CheckBox
        CorrGastrulationButton         matlab.ui.control.StateButton
        BoxplotsTmRNAButton            matlab.ui.control.StateButton
        SplitAULabel                   matlab.ui.control.Label
        SplitHIghLow                   matlab.ui.control.EditField
        ButtonGroup                    matlab.ui.container.ButtonGroup
        MCPButton                      matlab.ui.control.ToggleButton
        PCPButton                      matlab.ui.control.ToggleButton
        meanButton                     matlab.ui.control.ToggleButton
        InputOutputButton              matlab.ui.control.StateButton
        TraceSelectorTab               matlab.ui.container.Tab
        TraceSelectorSlider            matlab.ui.control.Slider
        TraceSelectorFrameBox          matlab.ui.control.Label
        TraceSelectorFindLabelBox      matlab.ui.control.EditField
        TraceSelectorFindLabelText     matlab.ui.control.Label
        TraceSelectorFindLabelButton   matlab.ui.control.Button
        TraceSelectorClearButton       matlab.ui.control.Button
        TraceSelectorExperimentText    matlab.ui.control.Label
        TraceSelectorExperimentList    matlab.ui.control.DropDown
        TraceSelectorLoadExpButton     matlab.ui.control.Button
        TraceSelectorLoadLamp          matlab.ui.control.Lamp
        TraceSelectorSaveSelectionButton  matlab.ui.control.Button
        TraceSelectorSaveName          matlab.ui.control.EditField
        TraceSelectorTextArea          matlab.ui.control.TextArea
        UIAxesTraceSelectorMovie       matlab.ui.control.UIAxes
        UIAxesTraceSelectorPlot        matlab.ui.control.UIAxes
        UIAxesTraceSelectorPlotEach    matlab.ui.control.UIAxes
        MetadataDropDownLabel          matlab.ui.control.Label
        MetadataDropDown               matlab.ui.control.DropDown
        loadMetadataButton             matlab.ui.control.Button
        MetadataLamp                   matlab.ui.control.Lamp
        goUserButton                   matlab.ui.control.Button
        UserLamp                       matlab.ui.control.Lamp
        UserEditFieldLabel             matlab.ui.control.Label
        UserEditField                  matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        Variables % used to transfer values within app
        MetaFilesPath % cell storing paths to metadata files
        MetaFiles % filenames for metadata files
        ProfileFilesPath
        ProfileFiles
        TempFileName
        TempFilePath
        Green
        Red
        MaxP
        XT
        YT
        ZT
        PaletteDefault
        Im %image loaded for traceselector
        Data
        ShiftPressed % to select cells only when shift is pressed
        Plot
    end
    
    methods (Access = private)
        
        
        function results = TraceSelectorClickCallback(app, source, eventargs)
            %disp('Executing')
                Coordinates = get(app.UIAxesTraceSelectorMovie,'CurrentPoint');
                %Coordinates = event.IntersectionPoint
                Coordinates = round(Coordinates(1,:));
                app.Data.Coordinates = Coordinates;
               
                TraceSelectorPlots(app)
        end
        
        function results = TraceSelectorPlots(app)
                f = round(app.TraceSelectorSlider.Value);
                app.TraceSelectorFrameBox.Text = ['frame: ',num2str(f),' / ',num2str(size(app.Im,3))];
                cla(app.UIAxesTraceSelectorMovie)
                imagesc(uint8(app.Im(:,:,f)),'HitTest','on','PickableParts','all','Parent',app.UIAxesTraceSelectorMovie,'ButtonDownFcn',@app.TraceSelectorClickCallback);

                NormF = app.Data.NormF;
                OnOff = app.Data.OnOff;
                CentX = app.Data.CentX(f,:);
                CentY = app.Data.CentY(f,:);
                Labels = app.Data.Properties.Label;
                Type = app.Data.Properties.Type;
                BurstPeak = app.Data.BurstPeak;
                TimeScale = app.Data.TimeScale;
                Selected = app.Data.Selected;
                
                Which = [];
                if ~isempty(app.Data.Coordinates)
                    eucledian = sqrt((CentX - app.Data.Coordinates(1)).^2 + (CentY - app.Data.Coordinates(2)).^2);
                    %axes(handles.axes2);cla
                    if min(eucledian) < 10
                        Which = find(eucledian == min(eucledian));
                        Selected = [Selected,Which];
                        Selected = unique(Selected);
                        cla(app.UIAxesTraceSelectorPlotEach)
                        plot(app.UIAxesTraceSelectorPlotEach,TimeScale,NormF(:,Which));
                        plot(app.UIAxesTraceSelectorPlotEach,TimeScale,OnOff(:,Which).*(2^app.Data.Parameters.Bits-1),'.');
                        plot(app.UIAxesTraceSelectorPlotEach,(BurstPeak{Which}-app.Data.Parameters.nc14+app.Data.Parameters.Delay).*app.Data.Parameters.TimeRes./60, BurstPeak{Which}.*0+(2^app.Data.Parameters.Bits-1),'.r','MarkerSize',10);  
                        ylim(app.UIAxesTraceSelectorPlotEach,[0, 2^app.Data.Parameters.Bits-1]);
                        xlim(app.UIAxesTraceSelectorPlotEach,[app.Data.TimeScale(1),app.Data.TimeScale(end)]);
                        title(app.UIAxesTraceSelectorPlotEach,join(['#',num2str(Labels(Which)),' ',Type(Which)]));
                        ylabel(app.UIAxesTraceSelectorPlotEach,'F (AU)'); 
                        xlabel(app.UIAxesTraceSelectorPlotEach,'t (min)');
                        app.Data.Selected = Selected;
                    end
                end
                if ~isempty(unique(Selected))
                    Label = Labels(unique(Selected))
                    num2str(Label')
                    app.TraceSelectorTextArea.Value = join(num2str(Label'),'\n');
                    plot(CentX(Selected), CentY(Selected),'.r','MarkerSize',20,'Parent',app.UIAxesTraceSelectorMovie)
                    cla(app.UIAxesTraceSelectorPlot)
                    plot(app.UIAxesTraceSelectorPlot,TimeScale,NormF(:,Selected));
                    plot(app.UIAxesTraceSelectorPlot,TimeScale,nanmean(NormF(:,Selected)'),'--r','LineWidth',2);
                    ylim(app.UIAxesTraceSelectorPlot,[0, 2^app.Data.Parameters.Bits-1]);
                    xlim(app.UIAxesTraceSelectorPlot,[app.Data.TimeScale(1),app.Data.TimeScale(end)]);
                    ylabel(app.UIAxesTraceSelectorPlot,'F (AU)'); 
                    xlabel(app.UIAxesTraceSelectorPlot,'t (min)');
               end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Green = [0.39,0.83,0.07];
            app.Red = [0.64,0.08,0.18];
            app.PaletteDefault = [130,130,130;80,80,80; %grey1, grey2. 1,2
                    142,183,36;105,139,34;62,81,16; %green1,green2,green3. 3:5
                    61,131,183;54,100,139;33,63,86; %blue1,blue2,blue3. 6:8
                    250,174,64;226,140,26; %yellow1, yellow2. 9:10
                    167,51,170;104,15,107; %pink1, pink2. 11:12
                    78,69,139; 54, 44,96; %purple1, purple2. 13:14
                    205,55,0]./255; %red. 15
             set(0,'DefaultAxesColorOrder',app.PaletteDefault)
             barh(app.Palettes,[ones(1,size(app.PaletteDefault,1));nan(1,size(app.PaletteDefault,1))],'stacked','BarWidth',1)
             ylim(app.Palettes,[0.5,1.5])
             xticks(app.Palettes,[0.5:1:size(app.PaletteDefault,1)])
             xticklabels(app.Palettes,[1:size(app.PaletteDefault,1)])
             yticklabels(app.Palettes,{'default','NaN'})
            %readtable([pwd,'/TTA_users.txt'],'Delimiter','\t','ReadVariableNames',false);
%             User = inputdlg('Username');
%             User = User{:};
%             app.UserEditField.Value = User;
%             MetaFiles = dir([pwd,'/',User,'/MetaFiles/metadata_*.txt']);
%             if isempty(MetaFiles) == true
%                 f = uifigure;
%                 uialert(f,'User does not exist: create a folder in app directory with username','Warning','Icon','warning');
%             else
%                 app.MetaFilesPath = {MetaFiles.folder};
%                 app.MetaFiles = {MetaFiles.name};
%                 app.MetadataDropDown.Items = app.MetaFiles;
%                 app.UserLamp.Color = app.Green;
%             end        
%             ProfileFiles = dir([pwd,'/',User,'/Profiles/*.txt']);
%             if isempty(ProfileFiles) == true
%                 f = uifigure;
%                 uialert(f,'No profiles found for this user','Warning','Icon','warning');
%             else
%                 app.ProfileFilesPath = {ProfileFiles.folder};
%                 app.ProfileFiles = {ProfileFiles.name};
%                 app.ProfileDropDown.Items = app.ProfileFiles;
%             end
            app.NewProfileTable.Data = cell2table({logical(false),'comp','nicknames','selection','cols','default','-20,60',1100,3000,NaN,NaN},...
                'VariableNames',{'Selected','Comparison','Labels','Region','Colors','Palette','XLim','YLim','Heatmaps','EarlySO','Notes'});
                %appRoot = ctfroot;
            app.ShiftPressed = 0;
            %
        end

        % Key press function: UIFig_LiveTrx
        function KeyPress(app, event)
            key = event.Key;
            FramesMoveArrow = 1;
            switch key
                case 'rightarrow'
                    if strcmp(app.TabGroup.SelectedTab.Title,'Add data')
                        f = min(round(app.Slider.Value+FramesMoveArrow),size(app.MaxP,4));
                        app.Slider.Value = f;
                        app.SliderLabel.Text = num2str(f);
                        imagesc(app.UIAxesMaxProj,uint8(app.MaxP(:,:,:,f)))
                    end
                    if strcmp(app.TabGroup.SelectedTab.Title,'Trace Selector')
                        f = min(round(app.TraceSelectorSlider.Value+FramesMoveArrow),size(app.Im,3));
                        app.TraceSelectorSlider.Value = f;
                        TraceSelectorPlots(app)
                        %app.TraceSelectorFrameBox.Text = ['frame: ',num2str(f),' / ',num2str(size(app.Im,3))];
                        %imagesc(uint8(app.Im(:,:,f)),'HitTest','on','PickableParts','all','Parent',app.UIAxesTraceSelectorMovie,'ButtonDownFcn',@app.TraceSelectorClickCallback);
                    end
                case 'leftarrow'
                    if strcmp(app.TabGroup.SelectedTab.Title,'Add data')
                        f = max(1,round(app.Slider.Value-FramesMoveArrow));
                        app.Slider.Value = f;
                        app.SliderLabel.Text = num2str(f);
                        imagesc(app.UIAxesMaxProj,uint8(app.MaxP(:,:,:,f)))
                    end
                    if strcmp(app.TabGroup.SelectedTab.Title,'Trace Selector')
                        f = max(1,round(app.TraceSelectorSlider.Value-FramesMoveArrow));
                        app.TraceSelectorSlider.Value = f;
                        TraceSelectorPlots(app)
                        %app.TraceSelectorFrameBox.Text = ['frame: ',num2str(f),' / ',num2str(size(app.Im,3))];
                        %imagesc(uint8(app.Im(:,:,f)),'HitTest','on','PickableParts','all','Parent',app.UIAxesTraceSelectorMovie,'ButtonDownFcn',@app.TraceSelectorClickCallback);
                    end
                case 'shift'
                    app.ShiftPressed = 1;
                    
                case 'enter'
            end
        end

        % Button pushed function: goUserButton
        function changeUser(app, event)
            User =  app.UserEditField.Value;
            MetaFiles = dir([pwd,'/',User,'/MetaFiles/metadata_*.txt']);
            if isempty(MetaFiles) == true
                f = uifigure;
                uialert(f,'User does not exist: create a folder in app directory with username','Warning','Icon','warning');
                app.UserLamp.Color = app.Red;
                app.MetadataDropDown.Items = {};
                app.MetadataLamp.Color = app.Red; 
            else
                app.MetaFilesPath = {MetaFiles.folder};
                app.MetaFiles = {MetaFiles.name};
                app.MetadataDropDown.Items = app.MetaFiles;
                app.UserLamp.Color = app.Green;
            end
            ProfileFiles = dir([pwd,'/',User,'/Profiles/*.txt']);
            if isempty(ProfileFiles) == true
                f = uifigure;
                uialert(f,'No profiles found for this user','Warning','Icon','warning');
            else
                app.ProfileFilesPath = {ProfileFiles.folder};
                app.ProfileFiles = {ProfileFiles.name};
                app.ProfileDropDown.Items = app.ProfileFiles;
            end  
            
        end

        % Button pushed function: loadMetadataButton
        function loadMetadata(app, event)
            MIndex = find(cellfun(@(x) strcmp(x,app.MetadataDropDown.Value),app.MetaFiles));
            info = readtable([app.MetaFilesPath{MIndex},'/',app.MetaFiles{MIndex}],'ReadVariableNames', true,'Delimiter', '\t');
            app.MetadataLamp.Color = app.Green; 
            
            info.Selected = false(height(info),1);
            info = movevars(info,{'Selected','Nickname','Rep'},'Before',1);
            if ~strcmp('Channel3',info.Properties.VariableNames)
                info.Channel3 = repmat({'-'},height(info),1);
            end
            info = movevars(info,{'Channel3'},'After','Channel2');
            app.Metadata.ColumnName = info.Properties.VariableNames;
            app.Metadata.RowName = flip(1:height(info));
            %app.Metadata.ColumnEditable = [true, false];
            app.Metadata.Data = flip(info);
            app.NicknameDropDown.Items = unique(info.Nickname);
            
            app.TraceSelectorExperimentList.Items = strcat(info.Nickname, '_',num2str(info.Rep));
           
        end

        % Button pushed function: LoadrawdataButton
        function loadRawData(app, event)
                [File,Path] = uigetfile('*.*','select file .lif with raw data','/Volumes/');
                app.TempFileName = File;
                app.TempFilePath = Path;
                app.PathEditField.Value = [Path,File];
                app.InitialProjectionLamp.Color = app.Red;
                app.AddMetadataLamp.Color = app.Red; 
                % change to default values
                app.FlipEditField.Value = '00';
                app.T0EditField.Value = 1;
                app.DelayEditField.Value = 0;
                app.FromEditField.Value = 1;
                app.ToEditField.Value = inf;
                app.SplitEarlyEditField.Value = 15;
                app.Channel1DropDown.Value = 'MCP';
                app.Channel2DropDown.Value = 'his';
                app.Channel3DropDown.Value = '-';

%
        end

        % Button pushed function: RuninitialProjectionButton
        function RunProjection(app, event)
                app.InitialProjectionLamp.Color = app.Red;
                %MS2_macroImageJ([app.TempFilePath,'/', app.TempFileName],0);
                MS2_macroImageJ(app.PathEditField.Value,0);
                Split = strsplit(app.PathEditField.Value,'/');
                app.TempFileName = Split{end};
                app.TempFilePath = [strjoin(Split(1:end-1),'/'),'/'];
                PathToSave = [app.TempFilePath,app.TempFileName];
                app.MaxP = Read3dRGB([PathToSave, '_CombinedY.tiff']);
                app.XT = Read3dRGB([PathToSave, '_XT.tiff']);
                app.YT = Read3dRGB([PathToSave, '_YT.tiff']);
                app.ZT = Read3dRGB([PathToSave, '_ZT.tiff']);
                %FlippedXT = permute(app.XT,[2,1,3,4]);
                %FlippedXT = [FlippedXT(1:size(FlippedXT,1)/2,:),FlippedXT(size(FlippedXT,1)/2+1:end,:)];
                imshow(uint8(app.XT),'Parent',app.UIAxesXT)
                imshow(uint8(app.YT),'Parent',app.UIAxesYT)
                imshow(uint8(app.ZT),'Parent',app.UIAxesZT)
                Frames = size(app.MaxP,4);
                app.Slider.Limits = [1,Frames];
                f = round(app.Slider.Value);
                app.SliderLabel.Text = num2str(f);
                imshow(uint8(app.MaxP(:,:,:,f)),'Parent',app.UIAxesMaxProj)
                app.InitialProjectionLamp.Color = app.Green;
        end

        % Callback function: Slider, Slider
        function UpdateSlider(app, event)
            f = round(event.Value);
            %f = round(app.Slider.Value);
            app.SliderLabel.Text = num2str(f);
            imagesc(uint8(app.MaxP(:,:,:,f)),'Parent',app.UIAxesMaxProj)
        end

        % Button pushed function: AddmetadataButton
        function AddMetadata(app, event)
                Path = app.TempFilePath;
                File = app.TempFileName;
                PathToSave = [Path,File]; 
                Name = '_3D/';
                reader = bfGetReader([Path,File]);
                [Bits,Width,Height, Channels, Slices, Frames, Frames0, XRes, YRes, ZRes,Zoom,TimeRes,Settings] = readMetadataBFOME(reader);
                try
                    XYRes=mean(round(mean([XRes,YRes]),2));
                    ZRes = mean(round(ZRes,2));
                end
                Nickname = app.NicknameDropDown.Value;
                Rep = app.RepeatEditField.Value;
                Flip = app.FlipEditField.Value;
                Flip = strjoin(arrayfun(@(x) num2str(x),Flip,'UniformOutput',false),',');
                nc14 = app.T0EditField.Value;
                Delay = app.DelayEditField.Value;
                From = app.FromEditField.Value;
                To = app.ToEditField.Value;
                SplitEarly = app.SplitEarlyEditField.Value;
                Channel1 = app.Channel1DropDown.Value;
                Channel2 = app.Channel2DropDown.Value;
                Channel3 = app.Channel3DropDown.Value;
                Notes = '';
                
                if strcmp(To,'NA')==1 || To == inf;  To=Frames-1;
                else
                    if isnumeric(To) == 0 
                        To = str2double(To); end 
                end
                Frames = To-From+1;
                
                NewMetadata = cell2table({false,Path,File,Name,Frames,Slices,Bits,TimeRes,XYRes,ZRes,...
            Delay,Nickname, Rep,Zoom,Flip,From,nc14,To,SplitEarly,Channel1,Channel2,Channel3,[Notes,' ',Settings]},...
                'VariableNames', {'Selected','Path','File','Name','Frames','Slices', 'Bits','TimeRes','XYRes','ZRes',...
                'Delay','Nickname','Rep','Zoom','Flip','From','nc14','To','SplitEarly','Channel1','Channel2','Channel3','Notes'});
            app.Metadata.Data = [NewMetadata;app.Metadata.Data];
            MIndex = find(cellfun(@(x) strcmp(x,app.MetadataDropDown.Value),app.MetaFiles));
            writetable(flip(app.Metadata.Data(:,2:end)),[app.MetaFilesPath{MIndex},'/',app.MetaFiles{MIndex}],'Delimiter', '\t');
            app.AddMetadataLamp.Color = app.Green; 

        end

        % Button pushed function: SelectallMetadataButton
        function SelectAllMetadata(app, event)
            app.Metadata.Data.Selected = true(height(app.Metadata.Data),1);
        end

        % Button pushed function: DeselectallMetadataButton
        function DeselectAllMetadata(app, event)
            app.Metadata.Data.Selected = false(height(app.Metadata.Data),1);
        end

        % Button pushed function: SearchRegExpButton
        function SearchRegExpMetadata(app, event)
            RegExp = app.SearchRegExpEditField.Value;
            Index = cellfun(@(x) ~isempty(x),regexp(app.Metadata.Data.Nickname, RegExp));
            app.Metadata.Data.Selected(Index) = true;
        end

        % Button pushed function: RunselectedButton_3
        function RunSelectedTracking(app, event)
            app.TrackingLamp.Color = app.Red;
            Index = find(app.Metadata.Data.Selected);
            info = app.Metadata.Data;
            for x = Index'
                set(0, 'DefaulttextInterpreter', 'none')
                %Divisions = 1; 
                %Spots = 0;
                InfoRow = info(x,:);
                app.ProgressTracking.Value = ['Running ',num2str(find(x == Index)),'/',num2str(length(Index))];
                %parameters = {InfoRow.Path{:}, InfoRow.File{:}, InfoRow.Name{:},InfoRow.Nickname{:},...
                    %InfoRow.Rep(:),InfoRow.From(:),InfoRow.nc14(:),InfoRow.To(:), InfoRow.TimeRes(:),...
                    %InfoRow.Delay(:),InfoRow.Flip{:}, InfoRow.SplitEarly(:),...
                    %InfoRow.Notes{:}, Divisions, Spots};
                
                if app.ProjectionsButton.Value == 1
                    %MS2_macroImageJ([InfoRow.Path{:}, InfoRow.File{:}],0);
                    Projections_app(InfoRow);
                end
                
                if app.ProjectionsOrthogonalButton.Value == 1
                    %MS2_macroImageJ_OrthViews([InfoRow.Path{:},InfoRow.File{:}],0,InfoRow.Channel1{:}, InfoRow.Channel2{:});
                    OrthViews_app(InfoRow);
                end
                
                if app.SegmtrackingButton.Value == 1
                    %histone settings as default, change to nucmemb if clicked memb
                    Settings = app.DefaultsettingsHis.Value;
                    %if strcmp(InfoRow.Channel1{:},'his') || strcmp(InfoRow.Channel2{:},'his') || strcmp(InfoRow.Channel3{:},'his')
                    %    Settings = app.DefaultsettingsHis.Value;
                    %end
                    if strcmp(InfoRow.Channel1{:},'nucmemb') || strcmp(InfoRow.Channel2{:},'nucmemb') || strcmp(InfoRow.Channel3{:},'nucmemb')
                        Settings = app.DefaultsettingsNucMemb.Value;
                    end
                    if strcmp(InfoRow.Channel1{:},'memb') || strcmp(InfoRow.Channel2{:},'memb') || strcmp(InfoRow.Channel3{:},'memb')
                        Settings = app.DefaultsettingsNucMemb.Value;
                    end
                    mainSegTrackMS2_3D_fromBF_combined(InfoRow,Settings)
                    %mainMS2_3D_fromBF(parameters,false)
                end              
               
                if app.AnalizetranscriptionButton.Value == 1
                    Spots = 0; %TO DO FIX THIS
                    if app.RegionnoneButton.Value == 1; Regions = 0;end
                    if app.RegiondrawButton.Value == 1; Regions = 1;end
                    if app.RegionoverwriteButton.Value == 1; Regions = 2;end
                    if app.ButtonATMCP.Value == 1; WhichAT = 'MCP';end
                    if app.ButtonATPCP.Value == 1; WhichAT = 'PCP';end
                    if app.ButtonATmean.Value == 1; WhichAT = 'mean';end
                    SettingsAT = app.DefaultsettingsAT.Value;
                    AnalyzeTraces(1,Regions,Spots, InfoRow, WhichAT, SettingsAT); %first arg is manual, second regions (0 no regions, 1 draw/rerun, 2 overwrite), third spots
                end

                if app.Properties3DButton.Value == 1
                    get_3Dprops_from_stats(InfoRow);
                end
                app.TrackingLamp.Color = app.Green;
                %clearvars('-except','Index','info', 'Paths','Files','Names','Nicknames','Reps','From','nc14','To','TimeRes','Delay','Flip','SplitEarly','Channel1','Channel2','Notes'); 
            end 
            
        end

        % Button pushed function: loadProfileButton
        function loadProfiles(app, event)
            PIndex = find(cellfun(@(x) strcmp(x,app.ProfileDropDown.Value),app.ProfileFiles));
            Profiles = readtable([app.ProfileFilesPath{PIndex},'/',app.ProfileFiles{PIndex}],'ReadVariableNames', true,'Delimiter', '\t');
            ProfileName = split(app.ProfileFiles{PIndex},'.');ProfileName = ProfileName{1};
            app.OutputfolderEditField.Value = [pwd,'/',app.UserEditField.Value,'/Analysis/',ProfileName,'/'];
            Profiles.Selected = false(height(Profiles),1);
            Profiles = movevars(Profiles,{'Selected'},'Before',1);
            app.ProfilesTable.ColumnName = Profiles.Properties.VariableNames;
            app.ProfilesTable.RowName = 1:height(Profiles);
            %app.ProfilesTable.ColumnEditable = [true, false];
            app.ProfilesTable.Data = Profiles;
            app.ProfileLamp.Color = app.Green;
            %T = strsplit(char(app.ProfilesTable.Data{1,2}),',')
            %(:)
            %cell(T(:))
        end

        % Button pushed function: NewProfileNameButton
        function CreateNewProfile(app, event)
            %app.ProfilesTable.ColumnName = {'Selected','Comparison','Labels','Region','Colors','Palette','XLim','YLim','Heatmaps','EarlySO'};
            app.ProfilesTable.Data = cell2table({logical(false),'comp','nicknames','selection','cols','default','-20,60',1100,3000,NaN,NaN},...
                'VariableNames',{'Selected','Comparison','Labels','Region','Colors','Palette','XLim','YLim','Heatmaps','EarlySO','Notes'});
            NewProfileName = app.NewProfileNameField.Value;
            if isempty(app.ProfileFilesPath)
                app.ProfileFilesPath{1} = app.ProfileFilesPath{1};
                app.ProfileFiles{1} = [NewProfileName,'.txt'];
            else
                app.ProfileFilesPath{end+1} = app.ProfileFilesPath{1};
                app.ProfileFiles{end+1} = [NewProfileName,'.txt'];
            end
            app.ProfileDropDown.Items = app.ProfileFiles;
            app.ProfileDropDown.Value = app.ProfileDropDown.Items{end};
            writetable(app.ProfilesTable.Data(:,2:end),[app.ProfileFilesPath{end},'/',app.ProfileFiles{end}],'Delimiter','\t')
        end

        % Button pushed function: AddnewProfileButton
        function AddNewLineProfile(app, event)
            NewLine = app.NewProfileTable.Data;
            NewLine.Selected = logical(false);
            NewLine = movevars(NewLine,{'Selected'},'Before',1);
            app.ProfilesTable.Data = [app.ProfilesTable.Data;NewLine];
            app.NewProfileTable.Data = cell2table({logical(false),'comp','nicknames','selection','cols','default','-20,60',1100,3000,NaN,NaN},...
                'VariableNames',{'Selected','Comparison','Labels','Region','Colors','Palette','XLim','YLim','Heatmaps','EarlySO','Notes'});
         
        end

        % Button pushed function: SaveProfileButton
        function SaveProfile(app, event)
            PIndex = find(cellfun(@(x) strcmp(x,app.ProfileDropDown.Value),app.ProfileFiles));
            ToSave = app.ProfilesTable.Data(:,2:end);
            writetable(ToSave,[app.ProfileFilesPath{PIndex},'/',app.ProfileFiles{PIndex}],'Delimiter','\t')
        end

        % Button pushed function: SelectallProfileButton
        function SelectAllProfiles(app, event)
            app.ProfilesTable.Data.Selected = true(height(app.ProfilesTable.Data),1);
        end

        % Button pushed function: DeselectallProfileButton
        function DeselectAllProfiles(app, event)
            app.ProfilesTable.Data.Selected = false(height(app.ProfilesTable.Data),1);
        end

        % Button pushed function: DeleteselectedProfileButton
        function DeleteSelectedProfiles(app, event)
            app.ProfilesTable.Data(app.ProfilesTable.Data.Selected,:) = [];
        end

        % Button pushed function: DeleteselectedMetadataButton
        function DeleteSelectedMetadata(app, event)
            app.Metadata.Data(app.Metadata.Data.Selected,:) = [];

        end

        % Button pushed function: SaveMetadataButton
        function SaveMetadata(app, event)
            MIndex = find(cellfun(@(x) strcmp(x,app.MetadataDropDown.Value),app.MetaFiles));
            writetable(flip(app.Metadata.Data(:,2:end)),[app.MetaFilesPath{MIndex},'/',app.MetaFiles{MIndex}],'Delimiter', '\t');
           
        end

        % Button pushed function: RunselectedButton_2
        function RunComp(app, event)
            app.TrackingLamp.Color = app.Red;
            Profiles = app.ProfilesTable.Data;
            Info = app.Metadata.Data;
            Exps = cell2table(Info.Nickname, 'VariableNames',{'Nickname'});
            Exps.Rep = Info.Rep;
            for x=1:size(Exps,1); Exp{x} = [Exps.Nickname{x},' ',num2str(Exps.Rep(x))];end
            Exps.Exp = Exp';
            PathF = app.OutputfolderEditField.Value;
            mkdir(PathF)
            Index = find(Profiles.Selected);
            
            for x = Index'
                ProfileEach = Profiles(x,:);
                app.ProgressComp.Value = ['Running ',num2str(find(x == Index)),'/',num2str(length(Index))];
                if strcmp(ProfileEach.Palette,'default')
                    Colors = str2double(strsplit(ProfileEach.Colors{:},','));
                    Palette = app.PaletteDefault(Colors,:);
                else
                    Palette = eval(char(ProfileEach.Palette));
                end
                SelectedN = ProfileEach.Comparison{:} ; SelectedN = strsplit(SelectedN,',');
                SelectedNames = ProfileEach.Labels{:} ; SelectedNames = strsplit(SelectedNames,',');
                Selection = ProfileEach.Region{:}; Selection = strsplit(Selection,',');
                XLim = str2double(strsplit(ProfileEach.XLim{:},','));
                YLim = ProfileEach.YLim;
                YLimGast = [0,str2double(app.YLimGast.Value)];
                SplitHighLow = str2double(app.SplitHIghLow.Value);
                Heatmaps = ProfileEach.Heatmaps;
                SplitEO = ProfileEach.EarlySO; if isnan(SplitEO); SplitEO = [];end
                set(0,'defaultAxesColorOrder',Palette)
                set(0,'defaultLegendAutoUpdate','on')
                set(0,'defaultAxesFontSize',16)

                if app.GastTimeCheckBox.Value ; WhichTime = 'Gast' ; else; WhichTime = 'nc14'; end
                if app.ConfirmCheckBox.Value ; Confirm = 1 ; else; Confirm = 0; end
                if length(SelectedN) == 1; PlotEach = 3; else; PlotEach = 1; end
                
                if app.MCPButton.Value == 1; WhichComp = 'MCP';end
                if app.PCPButton.Value == 1; WhichComp = 'PCP';end
                if app.meanButton.Value == 1; WhichComp = 'mean';end

                if app.MeanHeatmapsButton.Value == 1
                    if app.PlotselectedCheckBox.Value ; PlotSelected = 1 ; else; PlotSelected = 0; end
                    CompareMeans(SelectedN,PlotEach,Info,[PathF,'Means_Heatmaps/'],Exps, Selection, 1, XLim,Palette,Palette,[0,YLim],Heatmaps,SplitEO,PlotSelected,WhichTime,Confirm,WhichComp);
                end
                
                if app.BurstingButton.Value == 1
                    Parameters = char(join(app.DefaultsettingsBursting.Value));
                    if length(SelectedN) > length(Selection)
                        SelectionBursting = cellstr(repmat(Selection{:},length(SelectedN),1))';
                    end
                    CompareBursting(Info,SelectedN,SelectedNames,SelectionBursting,Palette,[PathF,'Bursting/'],Parameters);
                end
                
                if app.OnoffButton.Value == 1

                end
                
                if app.OfftimesButton.Value == 1

                end
                
                if app.InputOutputButton.Value == 1
                    CompareIO(SelectedN,PlotEach,Info,[PathF,'Means_Heatmaps/'],Exps, Selection, XLim,Palette,Palette,[0,YLim],SplitEO,WhichTime,Confirm);
                end
                
                if app.BoxplotsTmRNAButton.Value == 1
                    BoxplotsActivity(SelectedN,SelectedNames,1,Info,[PathF,'Bursting/'],Exps,Selection,Palette,XLim,SplitHighLow)
                end
                  
                
                if app.MembranesButton.Value == 1
                    Boo = [app.growthCheckBox.Value,app.boxplotslengthCheckBox.Value,app.corronsetvslengthCheckBox.Value];
                    mkdir([PathF,'Membrane_correlations/'])
                    CompareMembranes(SelectedN,SelectedNames,1,Info,[PathF,'Membrane_correlations/'],Exps, Selection, Palette, Boo);
                end
                
                if app.GastrulationButton.Value == 1
                    mkdir([PathF,'Gastrulation/'])
                    CompareGastrulation(SelectedN,PlotEach,Info,[PathF,'Gastrulation/'],Exps, Selection, XLim,Palette,YLimGast,SplitEO,WhichTime,Confirm);
                end
                if app.CorrGastrulationButton.Value == 1
                    mkdir([PathF,'Gastrulation/'])
                    CorrelateGastrulation(SelectedN,SelectedNames,PlotEach,Info,[PathF,'Gastrulation/'],Exps, Selection, Palette,Confirm)
                end
                if app.DPropertiesButton.Value == 1
                    PropertiestoRun = app.PropertiestoRun.Value;
                    PropertiestoRun = strsplit(PropertiestoRun,',');
                    for i = 1:length(PropertiestoRun)
                        Property = PropertiestoRun{i};
                        switch Property
                            case {'Volume','SurfaceArea'}
                                Limits = [0,350];
                            case {'Solidity','Eccentricity1','Eccentricity2','Eccentricity3'}
                                Limits = [0,1];
                            case {'PrincipalAxisLength_1','PrincipalAxisLength_2','PrincipalAxisLength_3','Perimeter1','Perimeter2','Perimeter3'}
                                Limits = [0,20];
                            case {'Area1','Area2','Area3'}
                                Limits = [0,25];
                        end
                        CompareProperty(SelectedN,1,Info,[PathF,'3DProperties/'],Exps,Selection, XLim,Palette, Palette,Limits,Property,SplitEO,WhichTime,Confirm)
                    end
                end
                    
                % save log. ie run X Y Z on :: with settings >>
            end
        end

        % Button pushed function: TraceSelectorLoadExpButton
        function TraceSelectorLoadExperiment(app, event)
            info = app.Metadata.Data;
            ExperimentList = strcat(info.Nickname, '_',num2str(info.Rep));
            SelectedExp = app.TraceSelectorExperimentList.Value;
            Index = strcmp(SelectedExp,ExperimentList);
            Parameters = info(Index,:);
            PathToSave = [Parameters.Path{:},Parameters.File{:},Parameters.Name{:},Parameters.File{:}]; 
            %minNumb = 10; Smooth = 3; minOn = 5; minPDis = 10; SplitShortLong = 60; SplitEarly = 20;
            try
                app.Im = Read3d([PathToSave, '_replacedF_maxGFP_MCP.tiff']);
            catch
                app.Im = Read3d([PathToSave, '_maxF_maxGFP.tiff']);
            end
            app.TraceSelectorSlider.Limits = [1,size(app.Im,3)];
            
%             f = round(app.TraceSelectorSlider.Value);
%             app.TraceSelectorFrameBox.Text = ['frame: ',num2str(f),' / ',num2str(size(app.Im,3))];
%             imagesc(uint8(app.Im(:,:,f)),'HitTest','on','PickableParts','all','Parent',app.UIAxesTraceSelectorMovie,'ButtonDownFcn',@app.TraceSelectorClickCallback);
            %plot(10, 10,'.r','MarkerSize',20,'HitTest','on','PickableParts','all','Parent',app.UIAxesTraceSelectorMovie);
            %set(app.Plot, 'ButtonDownFcn',@app.TraceSelectorClickCallback);

            %app.UIAxesTraceSelectorMovie.HitTest = 'on';
            app.UIAxesTraceSelectorMovie.PickableParts = 'all';
            zoom(app.UIAxesTraceSelectorMovie,'off');
            pan(app.UIAxesTraceSelectorMovie,'off');
            load([PathToSave,'_Data.mat']);
            app.Data = Data;
            TimeScale = ([1: size(app.Im,3)] - Parameters.nc14+Parameters.Delay).*Parameters.TimeRes./60;
            Selected = [app.Data.Properties.Type~='BG'];
            OnOff = app.Data.OnOff;
            minOn = 5;
            OnOff = CleanOnOff(OnOff,minOn);
            [OnOff] = CleanNaNs(app.Data.MedFilt,OnOff, minOn+2);
            [~,~,~,BurstPeak,~,~,~] = CountBursts(app.Data.MaxF,OnOff, Selected,5,1,Parameters.TimeRes);
            app.Data.BurstPeak = BurstPeak;
            app.Data.TimeScale = TimeScale;
            app.Data.NormF =((app.Data.MaxF-app.Data.Baseline').*app.Data.Baseline(1)./app.Data.Baseline');
            app.Data.Parameters = Parameters;
            app.Data.Selected = [];
            app.Data.Coordinates = [];
            TraceSelectorPlots(app)

            app.TraceSelectorLoadLamp.Color = app.Green;
        end

        % Callback function: TraceSelectorSlider, TraceSelectorSlider
        function UpdateSliderTraceSelector(app, event)
            %value = app.TraceSelectorSlider.Value;
            f = round(event.Value);
            TraceSelectorPlots(app)
            %app.TraceSelectorFrameBox.Text = ['frame: ',num2str(f),' / ',num2str(size(app.Im,3))];
            %imagesc(app.UIAxesTraceSelectorMovie,uint8(app.Im(:,:,f)))

        end

        % Button pushed function: TraceSelectorClearButton
        function ClearCombinedPlots(app, event)
            cla(app.UIAxesTraceSelectorPlot);
            cla(app.UIAxesTraceSelectorPlotEach);
            app.TraceSelectorTextArea.Value = '';
            app.Data.Selected = [];
            app.Data.Coordinates = [];
            TraceSelectorPlots(app)
        end

        % Button pushed function: TraceSelectorSaveSelectionButton
        function TraceSelectorSaveSelection(app, event)
            PathToSave = [app.Data.Parameters.Path{:},app.Data.Parameters.File{:},app.Data.Parameters.Name{:},'/TraceSelector/',app.Data.Parameters.File{:}];
            mkdir([app.Data.Parameters.Path{:},app.Data.Parameters.File{:},app.Data.Parameters.Name{:},'/TraceSelector/'])
            NewFig = figure('PaperOrientation',"landscape") % Open a new figure with handle f1
            NewFigAxes = axes(NewFig);
            copyobj(app.UIAxesTraceSelectorPlot.XAxis.Parent.Children,NewFigAxes)
            NewFigAxes.XLim = app.UIAxesTraceSelectorPlot.XLim;
            NewFigAxes.YLim = app.UIAxesTraceSelectorPlot.YLim;
            NewFigAxes.XLabel = app.UIAxesTraceSelectorPlot.XLabel;
            NewFigAxes.YLabel = app.UIAxesTraceSelectorPlot.YLabel;
            NewFigAxes.Title = title(app.TraceSelectorSaveName.Value);
            NewFigAxes.DataAspectRatio = app.UIAxesTraceSelectorPlot.DataAspectRatio;
            print(NewFig,[PathToSave,'_SelectedCells_',app.TraceSelectorSaveName.Value,'.pdf'],'-dpdf')
            fileID = fopen([PathToSave,'_SelectedCells_',app.TraceSelectorSaveName.Value,'.txt'],'w')
            fprintf(fileID,app.TraceSelectorTextArea.Value{:})
            fclose(fileID)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFig_LiveTrx = uifigure('Visible', 'off');
            app.UIFig_LiveTrx.Name = 'LiveTrx - Tracking & Analysis of Live Transcription';
            app.UIFig_LiveTrx.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.TabGroup = uitabgroup(app.UIFig_LiveTrx);
            app.AdddataTab = uitab(app.TabGroup);
            app.AdddataTab.Title = 'Add data';
            app.LoadrawdataButton = uibutton(app.AdddataTab, 'push');
            app.LoadrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @loadRawData, true);
            app.LoadrawdataButton.Text = 'Load raw data';
            app.PathEditFieldLabel = uilabel(app.AdddataTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.AdddataTab, 'text');
            app.RuninitialProjectionButton = uibutton(app.AdddataTab, 'push');
            app.RuninitialProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunProjection, true);
            app.RuninitialProjectionButton.Text = 'Run initial Projection';
            app.AddmetadataButton = uibutton(app.AdddataTab, 'push');
            app.AddmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @AddMetadata, true);
            app.AddmetadataButton.Text = 'Add metadata';
            app.AddMetadataLamp = uilamp(app.AdddataTab);
            app.SliderLabel = uilabel(app.AdddataTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.AdddataTab);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateSlider, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @UpdateSlider, true);
            app.NicknameDropDownLabel = uilabel(app.AdddataTab);
            app.NicknameDropDownLabel.Text = 'Nickname';
            app.NicknameDropDown = uidropdown(app.AdddataTab);
            app.NicknameDropDown.Items = {};
            app.NicknameDropDown.Editable = 'on';
            app.NicknameDropDown.Value = {};
            app.RepeatEditFieldLabel = uilabel(app.AdddataTab);
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.AdddataTab, 'numeric');
            app.Channel1DropDownLabel = uilabel(app.AdddataTab);
            app.Channel1DropDownLabel.Text = 'Channel 1';
            app.Channel1DropDown = uidropdown(app.AdddataTab);
            app.Channel1DropDown.Items = {'MCP', 'his', 'memb', 'nucmemb', 'mean', 'TL', '-'};
            app.Channel1DropDown.Editable = 'on';
            app.Channel1DropDown.Value = 'MCP';
            app.Channel2DropDownLabel = uilabel(app.AdddataTab);
            app.Channel2DropDownLabel.Text = 'Channel 2';
            app.Channel2DropDown = uidropdown(app.AdddataTab);
            app.Channel2DropDown.Items = {'MCP', 'his', 'memb', 'nucmemb', 'mean', 'TL', '-'};
            app.Channel2DropDown.Editable = 'on';
            app.Channel2DropDown.Value = 'his';
            app.InitialProjectionLamp = uilamp(app.AdddataTab);
            app.T0EditFieldLabel = uilabel(app.AdddataTab);
            app.T0EditFieldLabel.Text = 'Time0';
            app.T0EditField = uieditfield(app.AdddataTab, 'numeric');
            app.T0EditField.Tooltip = {'Frame to be considered as time 0'};
            app.T0EditField.Value = 1;
            app.FlipEditFieldLabel = uilabel(app.AdddataTab);
            app.FlipEditFieldLabel.Text = 'Flip';
            app.FlipEditField = uieditfield(app.AdddataTab, 'text');
            app.FlipEditField.Tooltip = {'Flip 00 - no flip'; 'Flip 01 - flip horizontally'; 'Flip 10 - flip vertically'; 'Flip 11 - flip both'; 'Flip XX1 - flip Z'};
            app.FlipEditField.Value = '00';
            app.DelayEditFieldLabel = uilabel(app.AdddataTab);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.AdddataTab, 'numeric');
            app.DelayEditField.Tooltip = {'if T0 is not captured'; ' approximate number of frames missing from T0 to start of movie'};
            app.ToEditFieldLabel = uilabel(app.AdddataTab);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.AdddataTab, 'numeric');
            app.ToEditField.Tooltip = {'Frame # for tracking and anlysis to end (if different from last frame)'};
            app.ToEditField.Value = Inf;
            app.SplitEarlyEditFieldLabel = uilabel(app.AdddataTab);
            app.SplitEarlyEditFieldLabel.Text = 'SplitEarly';
            app.SplitEarlyEditField = uieditfield(app.AdddataTab, 'numeric');
            app.SplitEarlyEditField.Tooltip = {'Time (minutes) at which the "real" transcription that wants to be considered starts'};
            app.SplitEarlyEditField.Value = 15;
            app.FromEditFieldLabel = uilabel(app.AdddataTab);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.AdddataTab, 'numeric');
            app.FromEditField.Tooltip = {'Frame # for tracking and anlysis to start (if different from 1)'};
            app.FromEditField.Value = 1;
            app.Channel3DropDownLabel = uilabel(app.AdddataTab);
            app.Channel3DropDownLabel.Text = 'Channel 3';
            app.Channel3DropDown = uidropdown(app.AdddataTab);
            app.Channel3DropDown.Items = {'MCP', 'his', 'memb', 'nucmemb', 'mean', 'TL', '-'};
            app.Channel3DropDown.Editable = 'on';
            app.Channel3DropDown.Value = '-';
            app.UIAxesMaxProj = uiaxes(app.AdddataTab);
            app.UIAxesMaxProj.XTick = [];
            app.UIAxesMaxProj.YTick = [];
            app.UIAxesXT = uiaxes(app.AdddataTab);
            title(app.UIAxesXT, 'XT', 'Interpreter', 'none')
            app.UIAxesXT.XTick = [];
            app.UIAxesXT.YTick = [];
            app.UIAxesZT = uiaxes(app.AdddataTab);
            title(app.UIAxesZT, 'ZT', 'Interpreter', 'none')
            app.UIAxesZT.XTick = [];
            app.UIAxesZT.YTick = [];
            app.UIAxesYT = uiaxes(app.AdddataTab);
            title(app.UIAxesYT, 'YT', 'Interpreter', 'none')
            app.UIAxesYT.XTick = [];
            app.UIAxesYT.YTick = [];
            app.RuntrackingTab = uitab(app.TabGroup);
            app.RuntrackingTab.Title = 'Run tracking';
            app.SelectdataPanel = uipanel(app.RuntrackingTab);
            app.SelectdataPanel.Title = '      Select data';
            app.SearchRegExpButton = uibutton(app.SelectdataPanel, 'push');
            app.SearchRegExpButton.ButtonPushedFcn = createCallbackFcn(app, @SearchRegExpMetadata, true);
            app.SearchRegExpButton.Text = 'Search RegExp';
            app.Metadata = uitable(app.SelectdataPanel);
            app.Metadata.ColumnName = {''};
            app.Metadata.RowName = {};
            app.Metadata.ColumnEditable = true;
            app.SelectallMetadataButton = uibutton(app.SelectdataPanel, 'push');
            app.SelectallMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllMetadata, true);
            app.SelectallMetadataButton.Text = 'Select all';
            app.DeselectallMetadataButton = uibutton(app.SelectdataPanel, 'push');
            app.DeselectallMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllMetadata, true);
            app.DeselectallMetadataButton.Text = 'Deselect all';
            app.SearchRegExpEditField = uieditfield(app.SelectdataPanel, 'text');
            app.SearchRegExpEditField.Value = 'e.g. ''^m5m8peve Dl$''';
            app.DeleteselectedMetadataButton = uibutton(app.SelectdataPanel, 'push');
            app.DeleteselectedMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedMetadata, true);
            app.DeleteselectedMetadataButton.Text = 'Delete selected';
            app.SaveMetadataButton = uibutton(app.SelectdataPanel, 'push');
            app.SaveMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMetadata, true);
            app.SaveMetadataButton.Text = 'Save metadata';
            app.RuntrackingPanel = uipanel(app.RuntrackingTab);
            app.RuntrackingPanel.Title = '      Run tracking';
            app.RunselectedButton_3 = uibutton(app.RuntrackingPanel, 'push');
            app.RunselectedButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunSelectedTracking, true);
            app.RunselectedButton_3.Text = 'Run selected';
            app.ProjectionsButton = uibutton(app.RuntrackingPanel, 'state');
            app.ProjectionsButton.Text = 'Projections';
            app.ProjectionsButton.Value = true;
            app.ProjectionsOrthogonalButton = uibutton(app.RuntrackingPanel, 'state');
            app.ProjectionsOrthogonalButton.Text = 'Projections & Orthogonal';
            app.SegmtrackingButton = uibutton(app.RuntrackingPanel, 'state');
            app.SegmtrackingButton.Text = 'Segmentation, tracking and transcription quantification';
            app.SegmtrackingButton.Value = true;
            app.Properties3DButton = uibutton(app.RuntrackingPanel, 'state');
            app.Properties3DButton.Text = '3D Properties';
            app.TrackingLamp = uilamp(app.RuntrackingPanel);
            app.SettingshistonesLabel = uilabel(app.RuntrackingPanel);
            app.SettingshistonesLabel.Text = 'Settings histones';
            app.DefaultsettingsHis = uitextarea(app.RuntrackingPanel);
            app.DefaultsettingsHis.Tooltip = {'Divisions = 1'; ''; 'Spots = 0'; ''; ''; 'MedFilt = 3'; ' % px'; 'LogRadius = 4./XYRes*0.4'; ''; 'ThresLevel = 0.05'; ''; 'WatershedParameter = 1'; ''; 'Thicken = round(1./XYRes)'; ' %in px'; 'RemoveSize = round(10./(XYRes^2.*ZRes))'; ' %10 um^3 in px volume. nc14 nuc is aprox 33 um^3'; ''; 'Distance = 6'; ' %um'; 'MaxN = 2'; ''};
            app.DefaultsettingsHis.Value = {'Divisions = 1;'; 'Spots = 0;'; ''; 'MedFilt = 3;'; 'LogRadius = 4./XYRes*0.4;'; 'ThresLevel = 0.05;'; 'WatershedParameter = 1;'; 'Thicken = round(1./XYRes);'; 'RemoveSize = round(10./(XYRes^2.*ZRes));'; ''; 'Distance = 6;'; 'MaxN = 2;'; ''; 'PrintInfo = 1;'};
            app.RegionButtonGroup = uibuttongroup(app.RuntrackingPanel);
            app.RegionnoneButton = uitogglebutton(app.RegionButtonGroup);
            app.RegionnoneButton.Text = 'none';
            app.RegiondrawButton = uitogglebutton(app.RegionButtonGroup);
            app.RegiondrawButton.Text = 'draw';
            app.RegiondrawButton.Value = true;
            app.RegionoverwriteButton = uitogglebutton(app.RegionButtonGroup);
            app.RegionoverwriteButton.Text = 'overwrite';
            app.MetadataDropDownLabel_4 = uilabel(app.RuntrackingPanel);
            app.MetadataDropDownLabel_4.Text = 'Regions';
            app.AnalizetranscriptionButton = uibutton(app.RuntrackingPanel, 'state');
            app.AnalizetranscriptionButton.Text = 'Analize transcription';
            app.AnalizetranscriptionButton.Value = true;
            app.DefaultsettingsNucMemb = uitextarea(app.RuntrackingPanel);
            app.DefaultsettingsNucMemb.Tooltip = {'Divisions = 1'; ''; 'Spots = 0'; ''; 'ThresLevel = 0.2 %FIXED THRESHOLD LEVEL FOR INITIAL SEGMENTATION'; '    %ThresLevel = 0.1 for nupGFP'; ' 0.25 for nupRFP high gain'; '    GaussFilt = 1'; ' %GAUSSIAN FILTERING RADIUS'; '    WatershedParameter = 0.25'; ' % AMOUNT OF WATERSHEDING (when lower more watershed'; ' when higher less)'; '    Thicken = 2'; ' % leave at 2'; '    RemoveSize = 10'; ' % objects smaller than this (in um^3) will be deleted'; '    RemoveSizeHigh = 200'; ' % objects bigger than this (in um^3) will be deleted'; 'Divisions = 1'; ' %leave at 1. how to fix conflicts when two nuclei match to 1. it will add new labels for both of them but most likely match can be fixed afterwards'; ''; 'Distance = 4'; ' % maximum distance (in um) for finding a closest neighbour'; 'MaxN = 5'; ' % maximum # of frames back that tries to find a closest neighbour'; ' if no one found'; ' a new label is created'};
            app.DefaultsettingsNucMemb.Value = {'Divisions = 1;'; 'Spots = 0'; ''; 'ThresLevel = 0.2'; 'GaussFilt = 1;'; 'WatershedParameter = 0.25;'; 'Thicken = 2;'; 'RemoveSize = 10;'; 'RemoveSizeHigh = 200; '; ''; 'Distance = 4; '; 'MaxN = 5;'; ''; 'PrintInfo = 1;'};
            app.DefaultsettingsCellMemb = uitextarea(app.RuntrackingPanel);
            app.DefaultsettingsCellMemb.Tooltip = {'MinContinuous: min t (min) at least one burst length'; 'Jitter/2 can''t be > BarW'};
            app.ProgressTracking = uieditfield(app.RuntrackingPanel, 'text');
            app.MembLengthButton = uibutton(app.RuntrackingPanel, 'state');
            app.MembLengthButton.Text = 'Membrane Lenght';
            app.SettingsnuclearmembranesLabel = uilabel(app.RuntrackingPanel);
            app.SettingsnuclearmembranesLabel.Text = 'Settings nuclear membranes';
            app.SettingscellmembranesLabel = uilabel(app.RuntrackingPanel);
            app.SettingscellmembranesLabel.Text = 'Settings cell membranes';
            app.RegionButtonAT = uibuttongroup(app.RuntrackingPanel);
            app.ButtonATMCP = uitogglebutton(app.RegionButtonAT);
            app.ButtonATMCP.Text = 'MCP';
            app.ButtonATMCP.Value = true;
            app.ButtonATPCP = uitogglebutton(app.RegionButtonAT);
            app.ButtonATPCP.Text = 'PCP';
            app.ButtonATmean = uitogglebutton(app.RegionButtonAT);
            app.ButtonATmean.Text = 'mean';
            app.DefaultsettingsAT = uitextarea(app.RuntrackingPanel);
            app.DefaultsettingsAT.Tooltip = {'Divisions = 1'; ''; 'Spots = 0'; ''; 'ThresLevel = 0.2 %FIXED THRESHOLD LEVEL FOR INITIAL SEGMENTATION'; '    %ThresLevel = 0.1 for nupGFP'; ' 0.25 for nupRFP high gain'; '    GaussFilt = 1'; ' %GAUSSIAN FILTERING RADIUS'; '    WatershedParameter = 0.25'; ' % AMOUNT OF WATERSHEDING (when lower more watershed'; ' when higher less)'; '    Thicken = 2'; ' % leave at 2'; '    RemoveSize = 10'; ' % objects smaller than this (in um^3) will be deleted'; '    RemoveSizeHigh = 200'; ' % objects bigger than this (in um^3) will be deleted'; 'Divisions = 1'; ' %leave at 1. how to fix conflicts when two nuclei match to 1. it will add new labels for both of them but most likely match can be fixed afterwards'; ''; 'Distance = 4'; ' % maximum distance (in um) for finding a closest neighbour'; 'MaxN = 5'; ' % maximum # of frames back that tries to find a closest neighbour'; ' if no one found'; ' a new label is created'};
            app.DefaultsettingsAT.Value = {'minNumb = 10; %minimum number of tracked frames for nuclei to be kept;'; ''; 'Smooth = 3;  %amount of median filtering to select ON traces;'; ''; 'FoldOverBaseline = 1.2; %MedFilt > FoldOverBaseline * Baseline  = ON;'; ''; 'minOn = 5; %minimum number of tracked nuclei is considered actively transcribing to be kept;'; '        '; 'minPDis = 10; %combination of time and space to fix tracking based on sudden ends or starts of transcription;'; ''};
            app.SettingsanalizetranscriptionLabel = uilabel(app.RuntrackingPanel);
            app.SettingsanalizetranscriptionLabel.Text = 'Settings analize transcription';
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.SelectexperimentstocomparePanel = uipanel(app.AnalyzeTab);
            app.SelectexperimentstocomparePanel.Title = '      Select experiments to compare';
            app.loadProfileButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.loadProfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadProfiles, true);
            app.loadProfileButton.Text = 'load';
            app.ProfileDropDownLabel = uilabel(app.SelectexperimentstocomparePanel);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.SelectexperimentstocomparePanel);
            app.ProfileDropDown.Items = {};
            app.ProfileDropDown.Value = {};
            app.NewProfileNameButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.NewProfileNameButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewProfile, true);
            app.NewProfileNameButton.Text = 'new profile';
            app.ProfilesTable = uitable(app.SelectexperimentstocomparePanel);
            app.ProfilesTable.ColumnName = {'Selection'; 'Comparison'; 'Labels'; 'Region'; 'Colors'; 'Palette'; 'XLim'; 'YLim'; 'Heatmaps'; 'EarlySO'; 'Notes'};
            app.ProfilesTable.RowName = {};
            app.ProfilesTable.ColumnEditable = true;
            app.NewProfileTable = uitable(app.SelectexperimentstocomparePanel);
            app.NewProfileTable.ColumnName = {'Selected'; 'Comparison'; 'Labels'; 'Region'; 'Colors'; 'Palette'; 'XLim'; 'YLim'; 'Heatmaps'; 'EarlySO'; 'Notes'};
            app.NewProfileTable.ColumnEditable = true;
            app.SelectallProfileButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.SelectallProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllProfiles, true);
            app.SelectallProfileButton.Text = 'Select all';
            app.DeleteselectedProfileButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.DeleteselectedProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedProfiles, true);
            app.DeleteselectedProfileButton.Text = 'Delete selected';
            app.AddnewProfileButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.AddnewProfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewLineProfile, true);
            app.AddnewProfileButton.Text = 'Add new';
            app.DeselectallProfileButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.DeselectallProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllProfiles, true);
            app.DeselectallProfileButton.Text = 'Deselect all';
            app.ProfileLamp = uilamp(app.SelectexperimentstocomparePanel);
            app.SaveProfileButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.SaveProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProfile, true);
            app.SaveProfileButton.Text = 'Save profile';
            app.NewProfileNameField = uieditfield(app.SelectexperimentstocomparePanel, 'text');
            app.NewProfileNameField.Value = 'profile name';
            app.DuplicateProfileButton = uibutton(app.SelectexperimentstocomparePanel, 'push');
            app.DuplicateProfileButton.Enable = 'off';
            app.DuplicateProfileButton.Text = 'Duplicate';
            app.Palettes = uiaxes(app.SelectexperimentstocomparePanel);
            app.Palettes.XTick = [];
            app.Palettes.YTick = [];
            app.RuncomparisonsPanel = uipanel(app.AnalyzeTab);
            app.RuncomparisonsPanel.Title = '      Run comparisons';
            app.PropertiestoRun = uieditfield(app.RuncomparisonsPanel, 'text');
            app.PropertiestoRun.Value = 'Volume,Solidity,SurfaceArea,PrincipalAxisLength_1,PrincipalAxisLength_2,PrincipalAxisLength_3,Area1,Area2,Area3,Perimeter1,Perimeter2,Perimeter3,Eccentricity1,Eccentricity2,Eccentricity3';
            app.RunselectedButton_2 = uibutton(app.RuncomparisonsPanel, 'push');
            app.RunselectedButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunComp, true);
            app.RunselectedButton_2.Text = 'Run selected';
            app.OutputfolderEditFieldLabel = uilabel(app.RuncomparisonsPanel);
            app.OutputfolderEditFieldLabel.Text = 'Output folder';
            app.OutputfolderEditField = uieditfield(app.RuncomparisonsPanel, 'text');
            app.MeanHeatmapsButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.MeanHeatmapsButton.Text = 'Mean & Heatmaps';
            app.BurstingButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.BurstingButton.Text = 'Bursting';
            app.OnoffButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.OnoffButton.Enable = 'off';
            app.OnoffButton.Text = 'On/off';
            app.OfftimesButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.OfftimesButton.Enable = 'off';
            app.OfftimesButton.Text = 'Off times';
            app.MembranesButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.MembranesButton.Text = 'Membranes';
            app.GastrulationButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.GastrulationButton.Text = 'Gastrulation';
            app.DPropertiesButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.DPropertiesButton.Text = '3D Properties';
            app.RunCompLamp = uilamp(app.RuncomparisonsPanel);
            app.PlotselectedCheckBox = uicheckbox(app.RuncomparisonsPanel);
            app.PlotselectedCheckBox.Text = 'Plot selected';
            app.DefaultsettingsTextAreaLabel = uilabel(app.RuncomparisonsPanel);
            app.DefaultsettingsTextAreaLabel.Text = 'Default settings';
            app.DefaultsettingsBursting = uitextarea(app.RuncomparisonsPanel);
            app.DefaultsettingsBursting.Tooltip = {'MinContinuous: min t (min) at least one burst length'; 'Jitter/2 can''t be > BarW'};
            app.DefaultsettingsBursting.Value = {'Log = 1;'; 'minOn = 5;'; 'MinContinuous = 10; '; 'Jitter = 0.6; '; 'BarW = 0.4;'; 'FaceAlpha = 0.3;'; 'DotSize = 5;'; 'LineWidth = 1.5;'; 'FontSizeTitle = 10;'; 'FontSize = 8;'};
            app.growthCheckBox = uicheckbox(app.RuncomparisonsPanel);
            app.growthCheckBox.Text = 'growth';
            app.boxplotslengthCheckBox = uicheckbox(app.RuncomparisonsPanel);
            app.boxplotslengthCheckBox.Text = 'boxplots length';
            app.boxplotslengthCheckBox.Value = true;
            app.corronsetvslengthCheckBox = uicheckbox(app.RuncomparisonsPanel);
            app.corronsetvslengthCheckBox.Text = 'corr onset vs length';
            app.corronsetvslengthCheckBox.Value = true;
            app.YLimEditField_2Label = uilabel(app.RuncomparisonsPanel);
            app.YLimEditField_2Label.Text = 'YLim';
            app.YLimGast = uieditfield(app.RuncomparisonsPanel, 'text');
            app.YLimGast.Value = '0.1';
            app.ProgressComp = uieditfield(app.RuncomparisonsPanel, 'text');
            app.GastTimeCheckBox = uicheckbox(app.RuncomparisonsPanel);
            app.GastTimeCheckBox.Text = '0 = GastTime';
            app.ConfirmCheckBox = uicheckbox(app.RuncomparisonsPanel);
            app.ConfirmCheckBox.Text = 'Confirm';
            app.CorrGastrulationButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.CorrGastrulationButton.Text = 'Correlate Gastrulation';
            app.BoxplotsTmRNAButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.BoxplotsTmRNAButton.Text = {'Boxplots TmRNA'; ''};
            app.SplitAULabel = uilabel(app.RuncomparisonsPanel);
            app.SplitAULabel.Text = 'Split (AU)';
            app.SplitHIghLow = uieditfield(app.RuncomparisonsPanel, 'text');
            app.SplitHIghLow.Value = '6';
            app.ButtonGroup = uibuttongroup(app.RuncomparisonsPanel);
            app.MCPButton = uitogglebutton(app.ButtonGroup);
            app.MCPButton.Text = 'MCP';
            app.MCPButton.Value = true;
            app.PCPButton = uitogglebutton(app.ButtonGroup);
            app.PCPButton.Text = 'PCP';
            app.meanButton = uitogglebutton(app.ButtonGroup);
            app.meanButton.Text = 'mean';
            app.InputOutputButton = uibutton(app.RuncomparisonsPanel, 'state');
            app.InputOutputButton.Text = 'Input/Output';
            app.TraceSelectorTab = uitab(app.TabGroup);
            app.TraceSelectorTab.Title = 'Trace Selector';
            app.TraceSelectorSlider = uislider(app.TraceSelectorTab);
            app.TraceSelectorSlider.Limits = [0 1];
            app.TraceSelectorSlider.MajorTicks = [];
            app.TraceSelectorSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateSliderTraceSelector, true);
            app.TraceSelectorSlider.ValueChangingFcn = createCallbackFcn(app, @UpdateSliderTraceSelector, true);
            app.TraceSelectorSlider.MinorTicks = [];
            app.TraceSelectorSlider.Value = 0.266423;
            app.TraceSelectorFrameBox = uilabel(app.TraceSelectorTab);
            app.TraceSelectorFrameBox.Text = 'frame: 160 / 599';
            app.TraceSelectorFindLabelBox = uieditfield(app.TraceSelectorTab, 'text');
            app.TraceSelectorFindLabelBox.Enable = 'off';
            app.TraceSelectorFindLabelText = uilabel(app.TraceSelectorTab);
            app.TraceSelectorFindLabelText.Text = 'Find label #';
            app.TraceSelectorFindLabelButton = uibutton(app.TraceSelectorTab, 'push');
            app.TraceSelectorFindLabelButton.Enable = 'off';
            app.TraceSelectorFindLabelButton.Text = 'go';
            app.TraceSelectorClearButton = uibutton(app.TraceSelectorTab, 'push');
            app.TraceSelectorClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCombinedPlots, true);
            app.TraceSelectorClearButton.Text = 'clear';
            app.TraceSelectorExperimentText = uilabel(app.TraceSelectorTab);
            app.TraceSelectorExperimentText.Text = 'Experiment:';
            app.TraceSelectorExperimentList = uidropdown(app.TraceSelectorTab);
            app.TraceSelectorExperimentList.Items = {};
            app.TraceSelectorExperimentList.Value = {};
            app.TraceSelectorLoadExpButton = uibutton(app.TraceSelectorTab, 'push');
            app.TraceSelectorLoadExpButton.ButtonPushedFcn = createCallbackFcn(app, @TraceSelectorLoadExperiment, true);
            app.TraceSelectorLoadExpButton.Text = 'load';
            app.TraceSelectorLoadLamp = uilamp(app.TraceSelectorTab);
            app.TraceSelectorSaveSelectionButton = uibutton(app.TraceSelectorTab, 'push');
            app.TraceSelectorSaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @TraceSelectorSaveSelection, true);
            app.TraceSelectorSaveSelectionButton.Tooltip = {'currently selected cells will be saved in a text file with the given name and a plot with all of them'};
            app.TraceSelectorSaveSelectionButton.Text = 'save selection';
            app.TraceSelectorSaveName = uieditfield(app.TraceSelectorTab, 'text');
            app.TraceSelectorSaveName.Value = 'name';
            app.TraceSelectorTextArea = uitextarea(app.TraceSelectorTab);
            app.UIAxesTraceSelectorMovie = uiaxes(app.TraceSelectorTab);
            app.UIAxesTraceSelectorMovie.YDir = 'reverse';
            app.UIAxesTraceSelectorMovie.CLim = [0 255];
            app.UIAxesTraceSelectorMovie.XTick = [];
            app.UIAxesTraceSelectorMovie.YTick = [];
            app.UIAxesTraceSelectorPlot = uiaxes(app.TraceSelectorTab);
            app.UIAxesTraceSelectorPlot.XLim = [0 149.5];
            app.UIAxesTraceSelectorPlot.YLim = [0 4095];
            app.UIAxesTraceSelectorPlotEach = uiaxes(app.TraceSelectorTab);
            app.UIAxesTraceSelectorPlotEach.XLim = [0 149.5];
            app.UIAxesTraceSelectorPlotEach.YLim = [0 4095];
            app.MetadataDropDownLabel = uilabel(app.UIFig_LiveTrx);
            app.MetadataDropDownLabel.Text = 'Metadata';
            app.MetadataDropDown = uidropdown(app.UIFig_LiveTrx);
            app.MetadataDropDown.Items = {};
            app.MetadataDropDown.Value = {};
            app.loadMetadataButton = uibutton(app.UIFig_LiveTrx, 'push');
            app.loadMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @loadMetadata, true);
            app.loadMetadataButton.Text = 'load';
            app.MetadataLamp = uilamp(app.UIFig_LiveTrx);
            app.goUserButton = uibutton(app.UIFig_LiveTrx, 'push');
            app.goUserButton.ButtonPushedFcn = createCallbackFcn(app, @changeUser, true);
            app.goUserButton.Text = 'go';
            app.UserLamp = uilamp(app.UIFig_LiveTrx);
            app.UserEditFieldLabel = uilabel(app.UIFig_LiveTrx);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.UIFig_LiveTrx, 'text');
            app.UserEditField.Value = 'user';
            app.Image = uiimage(app.UIFig_LiveTrx);
            app.Image.ImageSource = 'logo3.png';
            % Show the figure after all components are created
            app.UIFig_LiveTrx.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveTrx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFig_LiveTrx)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFig_LiveTrx)
        end
    end
end